classdef InclinometerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        InclinometerStepperMotorControllerPanel  matlab.ui.container.Panel
        ResetArduinoButton         matlab.ui.control.Button
        MoveToStartButton          matlab.ui.control.Button
        MoveXMillimetersButton     matlab.ui.control.Button
        MoveXMillimetersEditField  matlab.ui.control.EditField
    end

    methods (Access = private)
        function ret_signal = move_x_mm(dis_mm)
            global ardiuno_serial;
            MOVE_X = 4;
            serial_string = strcat(num2str(MOVE_X),",",num2str(dis_mm));
            disp(serial_string);
            ret_signal = serial_communication(ardiuno_serial, serial_string);
            flush(s);
        end
        function ret_mm = return_to_start()
            global ardiuno_serial;
            MOVE_TO_START = 2;
            serial_string = strcat(num2str(MOVE_TO_START),",0,1");
            ret_mm = serial_communication(ardiuno_serial, serial_string);
            flush(s);
        end 
        function ret_signal = reset_arduino()
            global ardiuno_serial;
            RESET_ARDUINO = 16;
            serial_string = strcat(num2str(RESET_ARDUINO),",0,0");
            custom_write(s, serial_string);
            ret_signal = custom_read(arduino_serial);
            flush(s);
        end
        function sig = serial_communication(s, message)
            custom_write(s,message);
            pause(1);
            disp("wait");
            waitfor(s, "NumBytesAvailable");
            sig = custom_read(s);
        end
        function custom_write(s, message)
            flush(s);
            writeline(s,message);
        end
        function out = custom_read(s)
            out = readline(s);
            flush(s);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: MoveXMillimetersButton
        function move_x_milimeters(comp, event)
            milimeters = str2num(comp.MoveXMillimetersEditField.Value);
            disp(milimeters);
            move_x_mm(milimeters);
        end

        % Button pushed function: MoveToStartButton
        function move_to_start(comp, event)
            return_to_start();
        end

        % Button pushed function: ResetArduinoButton
        function arduino_reset(comp, event)
            reset_arduino();
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 288 219];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.InclinometerStepperMotorControllerPanel = uipanel(comp);
            comp.InclinometerStepperMotorControllerPanel.Title = 'Inclinometer Stepper Motor Controller';
            comp.MoveXMillimetersEditField = uieditfield(comp.InclinometerStepperMotorControllerPanel, 'text');
            comp.MoveXMillimetersButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.MoveXMillimetersButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @move_x_milimeters, true);
            comp.MoveXMillimetersButton.Text = 'Move X Millimeters';
            comp.MoveToStartButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.MoveToStartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @move_to_start, true);
            comp.MoveToStartButton.Text = 'Move To Start';
            comp.ResetArduinoButton = uibutton(comp.InclinometerStepperMotorControllerPanel, 'push');
            comp.ResetArduinoButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @arduino_reset, true);
            comp.ResetArduinoButton.Text = 'Reset Arduino';
        end
    end
end